classdef ModelGUI_2017b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CoralModelUIFigure              matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AbioticTab                      matlab.ui.container.Tab
        ClimateScenarioButtonGroup      matlab.ui.container.ButtonGroup
        RCP26Button                     matlab.ui.control.RadioButton
        RCP45Button                     matlab.ui.control.RadioButton
        RCP60Button                     matlab.ui.control.RadioButton
        RCP85Button                     matlab.ui.control.RadioButton
        OceanAcidificationCheckBox      matlab.ui.control.CheckBox
        SSTDataSetButtonGroup           matlab.ui.container.ButtonGroup
        ESM2MButton                     matlab.ui.control.RadioButton
        HadISSTButton                   matlab.ui.control.RadioButton
        BioticTab                       matlab.ui.container.Tab
        EvolutionCheckBox               matlab.ui.control.CheckBox
        SuperSymbiontsButtonGroup       matlab.ui.container.ButtonGroup
        NoneButton                      matlab.ui.control.RadioButton
        OnButton                        matlab.ui.control.RadioButton
        SavelongmortalityyearsButton    matlab.ui.control.RadioButton
        SuperSymbiontoptionsButtonGroup  matlab.ui.container.ButtonGroup
        AddspecifiedadvantageButton     matlab.ui.control.RadioButton
        UsemeanofsuperInitYearsButton   matlab.ui.control.RadioButton
        UsemaxofsuperInitYearsButton    matlab.ui.control.RadioButton
        Addspecifiedadvantageatendoffirst5yearmortalityButton  matlab.ui.control.RadioButton
        SuperOptionPanel                matlab.ui.container.Panel
        AtyearEditFieldLabel            matlab.ui.control.Label
        AtyearEditField                 matlab.ui.control.NumericEditField
        TemperatureadvantageCEditFieldLabel  matlab.ui.control.Label
        TemperatureadvantageCEditField  matlab.ui.control.NumericEditField
        ProgrammaticTab                 matlab.ui.container.Tab
        EverynreefsEditFieldLabel       matlab.ui.control.Label
        EverynreefsEditField            matlab.ui.control.NumericEditField
        ReefSubsetDropDownLabel         matlab.ui.control.Label
        ReefSubsetDropDown              matlab.ui.control.DropDown
        KeyReefsTextAreaLabel           matlab.ui.control.Label
        KeyReefsTextArea                matlab.ui.control.TextArea
        RunevenifnotspecifiedaboveLabel  matlab.ui.control.Label
        WorkerthreadsSliderLabel        matlab.ui.control.Label
        WorkerthreadsSlider             matlab.ui.control.Slider
        OutputsTab                      matlab.ui.container.Tab
        GlobalMapsCheckBox              matlab.ui.control.CheckBox
        GlobalCoralCoverCheckBox        matlab.ui.control.CheckBox
        PanelPopulationPlotsButtonGroup  matlab.ui.container.ButtonGroup
        AllreefsButton                  matlab.ui.control.RadioButton
        KeyreefsButton                  matlab.ui.control.RadioButton
        SavedataforcrossrunstressedreefplotsCheckBox  matlab.ui.control.CheckBox
        GenotypefigureCheckBox          matlab.ui.control.CheckBox
        GrowthratefigureCheckBox        matlab.ui.control.CheckBox
        RunTab                          matlab.ui.container.Tab
        GoButton_2                      matlab.ui.control.Button
        CheckSelectionsTextAreaLabel    matlab.ui.control.Label
        CheckSelectionsTextArea         matlab.ui.control.TextArea
        JSONformatLabel                 matlab.ui.control.Label
        SaveStateButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        SetupTab                        matlab.ui.container.Tab
        CoralModelEditFieldLabel        matlab.ui.control.Label
        CoralModelEditField             matlab.ui.control.EditField
        SelectDirectoryButton_code      matlab.ui.control.Button
        PathsLabel                      matlab.ui.control.Label
        SelectDirectoryButton_output    matlab.ui.control.Button
        OutputBaseEditFieldLabel        matlab.ui.control.Label
        OutputBaseEditField             matlab.ui.control.EditField
    end


    properties (Access = public)
        parObj    % parameterDictionary object with all variables for the coral model.
        superSym  % Super symbionts in use - for button state.
        gaugeSet  % Store one gauge per worker when a run starts.
        % This one hardwired line makes it possible start from a known location, but
        % must be hardcoded on each user's computer!
        GUIBase = 'C:/Users/Steve/Google Drive/Coral_Model_Steve/GUIState_AndRunHistory/';
    end

    methods (Access = private)
    
        % Set key reefs vector from the text in the GUI text box.
        % Save that and all other modelVars to a file.
        function [jVars] = setKeySaveState(app)
           keyReefs = [];
           % Save the keyReefs field even if the current setting is "All Reefs"
            txt = app.KeyReefsTextArea.Value;
            txt = txt{1};  % get string out of cell
            if strcmp(txt, '')
                keyReefs = [];
                disp('empty text for keys');
                disp(keyReefs);
            else
                keyReefs = str2num(txt);
                % Regardless of how this arrives we need one row.
                keyReefs = reshape(keyReefs, 1, []);
                if isempty(keyReefs)
                    error('Key reef list was not empty, but could not be understood.  Use only integers separated by spaces or commas.');
                else
                    [rows, ~] = size(keyReefs);
                    if rows > 1
                        error('Key reefs should be a row vector.  Do not use semicolons.');
                    end
                end
            end
 
            app.parObj.set('keyReefs', keyReefs);
            jVars = app.parObj.getJSON();
            app.CheckSelectionsTextArea.Value = jVars;
            %% Save to a temp file for use on restart.
            pf = fopen(fullfile(app.GUIBase, '/modelVars.txt'), 'w');
            if pf == -1
                error('Could not open modelVars in %s.\nPath = %s \n', app.GUIBase, path);
            else
                fprintf(pf, '%s', jVars);
                fclose(pf);
                fprintf('Saved %s\n', jVars);
            end
        end
        
        % Read all modelVars from a file (if it exists) and overwrite any built-in
        % defaults.
        function [found] = recoverState(app)
            %% Recover values from a previous run, if possible.
            % The GUI starts from a different directory than the model to be run.
            
            % To deal with a restart file we need a parameterDictionary object which
            % belongs to the model code, not the GUI.  If one is on the path, start with 
            % It otherwise, prompt now.
            % Note that this is fragile - more than one type of object can cause a 
            % valid 'which' return.
            w = which('parameterDictionary');
            % Example during development: 'D:\GitHub\Coral-Model-V12\parameterDictionary.m'

            if isempty(w) || strcmp(w, 'Not on MATLAB path')
                if ispc
                    base = 'C:\';
                else
                    base = '/';
                end
                existingCodePath = uigetdir(base, 'Model Code Directory');
                addpath(existingCodePath);
            elseif strcmp(w, 'variable')
                error('A variable called parameterDictionary exists, and should not.');
            else
                % Assume it's a full path to the type of object we want, but save the
                % path in case of ambiguity.
                [existingCodePath, ~, ~] = fileparts(w);
            end

 
            % Read the GUI's restart file if any and use the model to parse it.
            app.GUIBase
            pf = fopen(fullfile(app.GUIBase, 'modelVars.txt'), 'r');            
            if pf ~= -1
                app.parObj = parameterDictionary('handle', pf);
                found = true;
            else
                app.parObj = parameterDictionary();
                found = false;
            end
            savedCodePath = app.parObj.get('codeBase');
            
            % Path name formats can vary.  Standardize before comparing.  No need to change
            % the original variables because addpath does the right thing either way.
            d1 = fullfile(savedCodePath);
            if endsWith(d1, {'/', '\'})
                d1 = d1(1:end-1);
            end    
            d2 = fullfile(existingCodePath);
            if endsWith(d2, {'/', '\'})
                d2 = d2(1:end-1);
            end
            if ~found
                % No saved folder, use the one where the parameterDictionary was found.
                app.parObj.set('codeBase', existingCodePath);
            elseif found && ~strcmp(d1, d2)
                % Saved folder doesn't match the one entered interactively.
                resp = questdlg(sprintf('Saved model directory does not match the one you specified.  Do you want\n (1) %s or\n(2) %s?', ...
                    savedCodePath, existingCodePath), 'Model Choice', '1', '2', savedCodePath);
                switch resp
                    case savedCodePath
                        % The dictionary object is not from the desired code directory.  Recreate it in case
                        % the versions are different.
                        clear app.parObj
                        rmpath existingCodePath;
                        addpath savedCodePath;
                        app.parObj = parameterDictionary('handle', pf);
                    case existingCodePath
                        % User wants the one where the PD was found, not the saved one.
                        app.parObj.set('codeBase', existingCodePath);
                end
            end
            % With GUI in use, always use the progress bar.
            app.parObj.set('doProgressBar', true);
            % Model will need to know where progress files go!
            app.parObj.set('GUIBase', app.GUIBase);
            % Now that the code base is set in the PD, fill the UI field as well.
            app.CoralModelEditField.Value = app.parObj.get('codeBase');

            if pf ~= -1
                fclose(pf);
            end
        end
        
        % Update the visibility/editablity of the year and temperature advantage fields
        % base on current selections for other super symbionts.  This is called both after
        % user changes and during startup.
        function updateSuperFields(app)
            % These fields are a function only of whether super symbionts are enabled
            % and which option is chosen.
            yf = 'off';
            tf = 'off';
            if app.OnButton.Value
                selectedButton = app.SuperSymbiontoptionsButtonGroup.SelectedObject;
                txt = selectedButton.Text;
                switch txt
                    case 'Add specified advantage'
                        yf = 'on';
                        tf = 'on';
                    case 'Use mean of superInitYears'
                        yf = 'on';
                        tf = 'off';
                    case 'Use max of superInitYears'
                        yf = 'on';
                        tf = 'off';
                    case 'Add specified advantage at end of first 5-year mortality'
                        yf = 'off';
                        tf = 'on';
                    otherwise
                        error('Super sym selection %s is not supported in the GUI.', txt);
                end
            else
                yf = 'off';
                tf = 'off';
            end
            app.AtyearEditField.Enable = yf;
            app.TemperatureadvantageCEditField.Enable = tf;
            if strcmp('off', yf) && strcmp('off', tf)
                app.SuperOptionPanel.Visible = 'off';
            else
                app.SuperOptionPanel.Visible = 'on';
            end
        end
        
        function setWorkerSlide(app)
            c = parcluster('local');
            nw = c.NumWorkers;
            app.WorkerthreadsSlider.Limits = [1 nw];
            app.WorkerthreadsSlider.MajorTicks = [1 2:2:nw];
            app.WorkerthreadsSlider.MinorTicks = [1:1:nw];
            % Default will give 1 less than the number of cores.
            % TODO: start with the last-used value.
            nc = feature('numcores');
            fprintf('nw %d, nc %d\n', nw, nc);
            
            app.WorkerthreadsSlider.Value = max(1, min(nw, nc-1));
        end
        
        function folder_name = setUserDirectory(app, name, storeAs)
            if ispc
                base = 'C:\';
            else
                base = '/';
            end
            folder_name = uigetdir(base, name);
            % Model convention is to end paths with a separator.
            folder_name = strcat(folder_name, filesep);
            app.parObj.set(storeAs, folder_name);
            % Kludge to work around a MATLAB bug which hides the main window.
            app.CoralModelUIFigure.Visible = 'off';
            app.CoralModelUIFigure.Visible = 'on';
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                       
            %% The GUI can be launched in a way that doesn't put its own directory on the path!
            % Check for this key function and add a user-provided path if necessary.
            w = which('ProgressUpdater');
            fprintf('Prog updater check: %s \n', w);
            if isempty(w) || strcmp(w, 'Not on MATLAB path')
                if ispc
                    base = 'C:\';
                else
                    base = '/';
                end
                GUICodePath = uigetdir(base, 'GUI Code Directory');
                addpath(GUICodePath);
            end
            
            %% Set default values.            
            % Originally values were coded here - now parameterDictionary defaults are used.
            
            % This one variable is not used directly by the model, but it keeps
            % the buttons in the right state on restart.
            app.superSym = 'None';
            
            hasState = recoverState(app);
            
            if hasState
                app.SetupTab.ForegroundColor = [0, 0, 0];
            else
                app.SetupTab.ForegroundColor = [0.95, 0, 0];
                app.TabGroup.SelectedTab = app.SetupTab;
            end
            
            %% Make controls match the default or restart values.
            %% Abiotic defaults
            switch app.parObj.get('dataset')
                case 'ESM2M'
                    app.ESM2MButton.Value = true;
                case 'HadISST'
                    app.HadISSTButton.Value = true;
                otherwise
                    error('GUI is not set up for dataset %s', app.parObj.get('dataset'));
            end
                        
            switch app.parObj.get('RCP')
                case 'rcp26'
                    app.RCP26Button.Value = true;
                case 'rcp45'
                    app.RCP45Button.Value = true;
                case 'rcp60'
                    app.RCP60Button.Value = true;
                case 'rcp85'
                    app.RCP85Button.Value = true;
                otherwise
                    error('GUI is not set up for rcp %s', app.parObj.get('RCP'));
            end
            app.OceanAcidificationCheckBox.Value = app.parObj.get('OA');
            
            %% Biotic defaults
            app.EvolutionCheckBox.Value = app.parObj.get('E');
            % Start with symbiont options off or at lowest levels, so only changes from there
            % need to be coded explicitly:
            app.NoneButton.Value = true;
            app.AddspecifiedadvantageButton.Value = true;
            app.AtyearEditField.Value = cast(app.parObj.get('superStart'), 'double');
            app.TemperatureadvantageCEditField.Value = cast(app.parObj.get('superAdvantage'), 'double');
            app.SuperSymbiontoptionsButtonGroup.Visible = 'off';
            
            updateSuperFields(app);
            
            %% Programmatic defaults
            % reef subset
            sub = app.parObj.get('specialSubset');
            exActive = false;
            switch sub
                case 'no'
                    app.ReefSubsetDropDown.Value = 'All Reefs';
                case 'eq'
                    app.ReefSubsetDropDown.Value = 'Equatorial';
                case 'lo'
                    app.ReefSubsetDropDown.Value = 'Mid Latitude';
                case 'hi'
                    app.ReefSubsetDropDown.Value = 'High Latitude';
                case 'keyOnly'
                    app.ReefSubsetDropDown.Value = 'Only Key Reefs';
                case 'useEveryx'
                    app.ReefSubsetDropDown.Value = 'Skip (below)';
                    exActive = true;
                otherwise
                    error('Reef subset %s is not supported.', sub);
            end
            app.EverynreefsEditField.Value = double(app.parObj.get('everyx'));
            if exActive
                app.EverynreefsEditField.Enable = 'on';
                app.EverynreefsEditFieldLabel.Enable = 'on';
            else
                app.EverynreefsEditField.Enable = 'off';
                app.EverynreefsEditFieldLabel.Enable = 'off';                
            end
            
            
            kr = sort(app.parObj.get('keyReefs'));
            kr = reshape(kr, 1, []);

            charform = num2str(kr);            
            % Warning: if you convert with string(), you get an error saying
            % "'Value' must be a string or a vector cell array of strings" even
            % though the resulting variable tests as a string.
            app.KeyReefsTextArea.Value =  charform;
            
            app.WorkerthreadsSlider.Value = double(app.parObj.get('useThreads'));

            
            %% Setup defaults
            if ~isempty(app.parObj.get('codeBase'))
                app.CoralModelEditField.Value = app.parObj.get('codeBase');
            end
            if ~isempty(app.parObj.get('outputBase'))
                app.OutputBaseEditField.Value = app.parObj.get('outputBase');
            end
            % Note that the GUI is not 1:1 with the variables in the model.
            %{
            switch app.superSym
                
                case 'None'
                    app.NoneButton.Value = true;
            %}
            % First the super symbiont addition mode, which can determine what else is in use.
            isFixedT = false;
            switch app.parObj.get('superMode')
                case 0
                    isFixedT = true;
                    if app.parObj.get('superAdvantage') == 0.0
                        % All set
                    else
                        app.OnButton.Value = true;
                        app.SuperSymbiontoptionsButtonGroup.Visible = 'on';
            
                    end
                case 1
                    app.UsemeanofsuperInitYearsButton.Value = true;
                    app.OnButton.Value = true;
                    app.SuperSymbiontoptionsButtonGroup.Visible = 'on';
            
                case 2
                    app.UsemaxofsuperInitYearsButton.Value = true;
                    app.OnButton.Value = true;
                    app.SuperSymbiontoptionsButtonGroup.Visible = 'on';
            
                case 6
                    isFixedT = true;
                    app.Addspecifiedadvantageatendoffirst5yearmortalityButton.Value = true;
                    app.OnButton.Value = true;
                    app.SuperSymbiontoptionsButtonGroup.Visible = 'on';
                   
                otherwise
                    error('GUI is not set up for super symbiont mode %d', app.parObj.get('superMode'));
            end
            % Modes with a fixed temperature are the same as doing nothing if the advantage
            % value is zero.  Modes with variable temperatures don't use that value.  Modes with
            % computed times don't use "At year".
            if strcmp(app.superSym, 'Save long mortality years')
                disp('in 2');
                app.SavelongmortalityyearsButton.Value = true;
                app.SuperSymbiontoptionsButtonGroup.Visible = 'off';           
            elseif strcmp(app.superSym, 'None') || (app.parObj.get('superAdvantage') == 0.0 && isFixedT)
                disp('in 1');
                app.NoneButton.Value = true;
                app.SuperSymbiontoptionsButtonGroup.Visible = 'off';
            
            else
                disp('in 3');
                app.OnButton.Value = true;
                app.SuperSymbiontoptionsButtonGroup.Visible = 'on';
            end
            updateSuperFields(app);
            
            
            % Output defaults
            app.GlobalCoralCoverCheckBox.Value = app.parObj.get('doCoralCoverFigure');
            app.GlobalMapsCheckBox.Value = app.parObj.get('doCoralCoverMaps');
          
            if app.parObj.get('allFigs')
                app.AllreefsButton.Value = true;
            else
                app.KeyreefsButton.Value = true;
            end
            
            app.SavedataforcrossrunstressedreefplotsCheckBox.Value = app.parObj.get('doDetailedStressStats');
            app.GenotypefigureCheckBox.Value = app.parObj.get('doGenotypeFigure');
            app.GrowthratefigureCheckBox.Value = app.parObj.get('doGrowthRateFigure');

            
            %% GUI doesn't support tab reordering!  Kludge it here:
            %{
            Rebuilt the tab, but keep this as a sample.
            tabs = app.TabGroup.Children;
            ttab = tabs(5);
            tabs(5) = tabs(4);
            tabs(4) = ttab;
            app.TabGroup.Children = tabs;
            %}
            
            
        end

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
        end

        % Value changed function: AtyearEditField
        function AtyearEditFieldValueChanged(app, event)
            value = app.AtyearEditField.Value;
            app.parObj.set('superStart', value);
        end

        % Selection changed function: ClimateScenarioButtonGroup
        function ClimateScenarioButtonGroupSelectionChanged(app, event)
            selectedButton = app.ClimateScenarioButtonGroup.SelectedObject;
            txt = selectedButton.Text;
            txt = replace(txt, '.', '');
            app.parObj.set('RCP', lower(replace(txt, ' ', '')));
            
                
        end

        % Value changed function: EverynreefsEditField
        function EverynreefsEditFieldValueChanged(app, event)
            value = app.EverynreefsEditField.Value;
            if app.ReefSubsetDropDown.Value == 'Skip (below)'
                app.parObj.set('everyx', value);
            else
                error('It should be impossible to enter a value when the dropdown is not set to "skip".');
            end
        end

        % Value changed function: EvolutionCheckBox
        function EvolutionCheckBoxValueChanged(app, event)
            value = app.EvolutionCheckBox.Value;
            app.parObj.set('E', value);
        end

        % Value changed function: GlobalCoralCoverCheckBox
        function GlobalCoralCoverCheckBoxValueChanged(app, event)
            value = app.GlobalCoralCoverCheckBox.Value;
            app.parObj.set('doCoralCoverFigure', value);
        end

        % Callback function
        function GoButton_2Pushed(app, event)
            setKeySaveState(app);
                    
            
            % Make the variables local and call the model!
            %scriptVars = app.modelVars;
            
            % For each thread make a new slider as a progress bar.
            
            nw = app.parObj.get('useThreads');
            % Ints mess up the axes!
            nw = double(nw);
            %{
            maxBarY = 300;
            y = 40;
            htOne = 40;
            htN = 26;
            dy = 50;
            if nw*dy > maxBarY
                dy = round(maxBarY / (nw+1));
                htOne = max(30, round(dy * 0.85));
                htN = min(26, round(dy * 0.85));
            end
            %}
            %fprintf('Setting up axes for nw = %d\n', nw);
            ax = app.UIAxes;
            xlim(ax, [0 nw+1]);  % No difference between xlim() and XLim = 
            ylim(ax, [0 100]);

            %ax.XLim = [0 nw+1];  % This line shortens the y axis.
            %ax.YLim = [0 100];   % This line collapses the x axis and y grows to about the original size.
            ax.XTick = 0:nw+1;
            labs = ax.XTickLabel;
            disp(labs)
            labs{1} = '';
            labs{nw+2} = '';
            ax.XTickLabel = labs;


            t = timer();
            %{
            This code mostly worked, looks like the doc example, but it triggers an
            error on the first model run which causes the bars not to get drawn.
            t.StartFcn = @(~, thisEvent)ProgressUpdater(app.GUIBase,  ax, nw, 0);
            t.TimerFcn = @(~, thisEvent)ProgressUpdater(app.GUIBase, ax, nw);
            t.StopFcn = @(~, thisEvent)ProgressUpdater(app.GUIBase, ax, nw, 100);
            %}
            t.StartFcn = {@ProgressUpdater, app.GUIBase,  ax, nw, 0};
            t.TimerFcn = {@ProgressUpdater, app.GUIBase, ax, nw};
            t.StopFcn =  {@ProgressUpdater, app.GUIBase, ax, nw, 100};
            t.Period = 1;
            t.TasksToExecute = 100;
            t.ExecutionMode = 'fixedRate';
            start(t);
            pause(0.1);

            if ~isempty(app.parObj.get('codeBase'))
                startPath = cd;
                cd(app.parObj.get('codeBase'))
                aCoralModel(app.parObj.getJSON())
                cd(startPath);
            else
            end
            
            stop(t);
            delete(t);
        end

        % Value changed function: OceanAcidificationCheckBox
        function OceanAcidificationCheckBoxValueChanged(app, event)
            value = app.OceanAcidificationCheckBox.Value;
            app.parObj.set('OA', value);
            
        end

        % Value changed function: ReefSubsetDropDown
        function ReefSubsetDropDownValueChanged(app, event)
            value = app.ReefSubsetDropDown.Value;
            fieldOff = true;
            keyOff = false;
            switch value
                case 'Skip (below)'
                    app.EverynreefsEditField.Enable = 'on';
                    app.EverynreefsEditFieldLabel.Enable = 'on';
                    app.parObj.set('everyx', app.EverynreefsEditField.Value);
                    app.parObj.set('specialSubset', 'useEveryx');                
                    fieldOff = false;
                case 'All Reefs'
                    app.parObj.set('everyx', 1);
                    app.parObj.set('specialSubset', 'no');                
                    keyOff = true;
                case 'Only Key Reefs'
                    app.parObj.set('specialSubset', 'keyOnly');
                case 'Equatorial'
                    app.parObj.set('specialSubset', 'eq');                
                case 'Mid Latitude'
                    app.parObj.set('specialSubset', 'lo');                
                case 'High Latitude'
                    app.parObj.set('specialSubset', 'hi');                
                otherwise
                    error('Reef selection dropdown field %s is unsupported!', value);
            end
            if fieldOff
                app.EverynreefsEditField.Enable = 'off';
                app.EverynreefsEditFieldLabel.Enable = 'off';
            else
                app.EverynreefsEditField.Enable = 'on';
                app.EverynreefsEditFieldLabel.Enable = 'on';
            end
            if keyOff
                app.KeyReefsTextArea.Enable = 'off';
            else
                app.KeyReefsTextArea.Enable = 'on';
            end
                
        end

        % Button pushed function: SaveStateButton
        function SaveStateButtonPushed(app, event)
            setKeySaveState(app);
        end

        % Selection changed function: SuperSymbiontoptionsButtonGroup
        function SuperSymbiontoptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.SuperSymbiontoptionsButtonGroup.SelectedObject;
            txt = selectedButton.Text;
            switch txt
                case 'Add specified advantage'
                    app.parObj.set('superMode', 0);
                case 'Use mean of superInitYears'
                    app.parObj.set('superMode', 1);
                case 'Use max of superInitYears'
                    app.parObj.set('superMode', 2);
                case 'Add specified advantage at end of first 5-year mortality'
                    app.parObj.set('superMode', 5);
                otherwise
                    error('Super sym option selection %s is not supported in the GUI.', txt);
            end
            updateSuperFields(app);
        end

        % Selection changed function: SuperSymbiontsButtonGroup
        function SuperSymbiontsButtonGroupSelectionChanged(app, event)
            selectedButton = app.SuperSymbiontsButtonGroup.SelectedObject;
            txt = selectedButton.Text;
            app.superSym = txt;
            newMortYears = false;
            switch txt
                case 'None'
                    app.parObj.set('superMode', 0);
                    app.parObj.set('superAdvantage', 0.0);
                    app.SuperSymbiontoptionsButtonGroup.Visible = 'off';
                case 'On'
                    app.parObj.set('superAdvantage', 0.0);
                    app.SuperSymbiontoptionsButtonGroup.Visible = 'on';
            
                case 'Save long mortality years'
                    app.parObj.set('newMortYears', true);
                    app.parObj.set('superMode', 0);
                    app.parObj.set('superAdvantage', 0.0);
                    app.SuperSymbiontoptionsButtonGroup.Visible = 'off';
                otherwise
                    error('No logic for button %s.', txt);
            end
            updateSuperFields(app);
        end

        % Value changed function: TemperatureadvantageCEditField
        function TemperatureadvantageCEditFieldValueChanged(app, event)
            value = app.TemperatureadvantageCEditField.Value;
            app.parObj.set('superAdvantage', value);
        end

        % Value changed function: WorkerthreadsSlider
        function WorkerthreadsSliderValueChanged(app, event)
            value = round(app.WorkerthreadsSlider.Value);
            app.WorkerthreadsSlider.Value = value;
            app.parObj.set('useThreads', value);
        end

        % Button pushed function: SelectDirectoryButton_code
        function SelectDirectoryButton_codePushed(app, event)
            fn = setUserDirectory(app, 'Model Code Directory', 'codeBase')
            app.CoralModelEditField.Value = fn;

        end

        % Button pushed function: SelectDirectoryButton_output
        function SelectDirectoryButton_outputPushed(app, event)
            fn = setUserDirectory(app, 'Output Base Directory', 'outputBase')
            app.OutputBaseEditField.Value = fn;

        end

        % Callback function
        function SelectDirectoryButton_code_2Pushed(app, event)
            % obsolete
        end

        % Value changed function: GlobalMapsCheckBox
        function GlobalMapsCheckBoxValueChanged(app, event)
            value = app.GlobalMapsCheckBox.Value;
            app.parObj.set('doCoralCoverMaps', value);
        end

        % Selection changed function: PanelPopulationPlotsButtonGroup
        function PlotForKeyButtonGroupSelectionChanged(app, event)
            selectedButton = app.PanelPopulationPlotsButtonGroup.SelectedObject;
            txt = selectedButton.Text;
            switch txt
                case 'All reefs'
                app.parObj.set('allFigs', true);
                case 'Key reefs'
                app.parObj.set('allFigs', false);
                otherwise
                    error('Only All reefs and Key reefs are supported for this panel.');
            end
        end

        % Value changed function: 
        % SavedataforcrossrunstressedreefplotsCheckBox
        function CrossRunStressDataCheckBoxValueChanged(app, event)
            value = app.SavedataforcrossrunstressedreefplotsCheckBox.Value;
            app.parObj.set('doDetailedStressStats', value);

        end

        % Value changed function: GenotypefigureCheckBox
        function GenotypefigureCheckBoxValueChanged(app, event)
            value = app.GenotypefigureCheckBox.Value;
                app.parObj.set('doGenotypeFigure', value);
        end

        % Value changed function: GrowthratefigureCheckBox
        function GrowthratefigureCheckBoxValueChanged(app, event)
            value = app.GrowthratefigureCheckBox.Value;
            app.parObj.set('doGrowthRateFigure', value);
        end

        % Selection changed function: SSTDataSetButtonGroup
        function SSTDataSetButtonGroupSelectionChanged(app, event)
            selectedButton = app.SSTDataSetButtonGroup.SelectedObject;
            txt = selectedButton.Text;
            switch txt
                case 'ESM2M'
                    app.parObj.set('dataset', 'ESM2M');
                case 'HadISST'
                    app.parObj.set('dataset', 'HadISST');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CoralModelUIFigure = uifigure;
            app.CoralModelUIFigure.Name = 'Coral Model';
            app.TabGroup = uitabgroup(app.CoralModelUIFigure);
            app.AbioticTab = uitab(app.TabGroup);
            app.AbioticTab.Title = 'Abiotic';
            app.ClimateScenarioButtonGroup = uibuttongroup(app.AbioticTab);
            app.ClimateScenarioButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ClimateScenarioButtonGroupSelectionChanged, true);
            app.ClimateScenarioButtonGroup.Title = 'Climate Scenario';
            app.RCP26Button = uiradiobutton(app.ClimateScenarioButtonGroup);
            app.RCP26Button.Text = 'RCP 2.6';
            app.RCP26Button.Value = true;
            app.RCP45Button = uiradiobutton(app.ClimateScenarioButtonGroup);
            app.RCP45Button.Text = 'RCP 4.5';
            app.RCP60Button = uiradiobutton(app.ClimateScenarioButtonGroup);
            app.RCP60Button.Text = 'RCP 6.0';
            app.RCP85Button = uiradiobutton(app.ClimateScenarioButtonGroup);
            app.RCP85Button.Text = 'RCP 8.5';
            app.OceanAcidificationCheckBox = uicheckbox(app.AbioticTab);
            app.OceanAcidificationCheckBox.ValueChangedFcn = createCallbackFcn(app, @OceanAcidificationCheckBoxValueChanged, true);
            app.OceanAcidificationCheckBox.Text = 'Ocean Acidification';
            app.SSTDataSetButtonGroup = uibuttongroup(app.AbioticTab);
            app.SSTDataSetButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SSTDataSetButtonGroupSelectionChanged, true);
            app.SSTDataSetButtonGroup.Title = 'SST Data Set';
            app.ESM2MButton = uiradiobutton(app.SSTDataSetButtonGroup);
            app.ESM2MButton.Text = 'ESM2M';
            app.ESM2MButton.Value = true;
            app.HadISSTButton = uiradiobutton(app.SSTDataSetButtonGroup);
            app.HadISSTButton.Text = 'HadISST';
            app.BioticTab = uitab(app.TabGroup);
            app.BioticTab.Title = 'Biotic';
            app.EvolutionCheckBox = uicheckbox(app.BioticTab);
            app.EvolutionCheckBox.ValueChangedFcn = createCallbackFcn(app, @EvolutionCheckBoxValueChanged, true);
            app.EvolutionCheckBox.Text = 'Evolution';
            app.SuperSymbiontsButtonGroup = uibuttongroup(app.BioticTab);
            app.SuperSymbiontsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SuperSymbiontsButtonGroupSelectionChanged, true);
            app.SuperSymbiontsButtonGroup.Title = 'Super Symbionts';
            app.NoneButton = uiradiobutton(app.SuperSymbiontsButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.OnButton = uiradiobutton(app.SuperSymbiontsButtonGroup);
            app.OnButton.Text = 'On';
            app.SavelongmortalityyearsButton = uiradiobutton(app.SuperSymbiontsButtonGroup);
            app.SavelongmortalityyearsButton.Text = 'Save long mortality years';
            app.SuperSymbiontoptionsButtonGroup = uibuttongroup(app.BioticTab);
            app.SuperSymbiontoptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SuperSymbiontoptionsButtonGroupSelectionChanged, true);
            app.SuperSymbiontoptionsButtonGroup.Title = 'Super Symbiont options';
            app.AddspecifiedadvantageButton = uiradiobutton(app.SuperSymbiontoptionsButtonGroup);
            app.AddspecifiedadvantageButton.Text = 'Add specified advantage';
            app.AddspecifiedadvantageButton.Value = true;
            app.UsemeanofsuperInitYearsButton = uiradiobutton(app.SuperSymbiontoptionsButtonGroup);
            app.UsemeanofsuperInitYearsButton.Text = 'Use mean of superInitYears';
            app.UsemaxofsuperInitYearsButton = uiradiobutton(app.SuperSymbiontoptionsButtonGroup);
            app.UsemaxofsuperInitYearsButton.Text = 'Use max of superInitYears';
            app.Addspecifiedadvantageatendoffirst5yearmortalityButton = uiradiobutton(app.SuperSymbiontoptionsButtonGroup);
            app.Addspecifiedadvantageatendoffirst5yearmortalityButton.Text = 'Add specified advantage at end of first 5-year mortality';
            app.SuperOptionPanel = uipanel(app.BioticTab);
            app.AtyearEditFieldLabel = uilabel(app.SuperOptionPanel);
            app.AtyearEditFieldLabel.Text = 'At year';
            app.AtyearEditField = uieditfield(app.SuperOptionPanel, 'numeric');
            app.AtyearEditField.Limits = [1861 2099];
            app.AtyearEditField.ValueDisplayFormat = '%.0f';
            app.AtyearEditField.ValueChangedFcn = createCallbackFcn(app, @AtyearEditFieldValueChanged, true);
            app.AtyearEditField.Value = 2035;
            app.TemperatureadvantageCEditFieldLabel = uilabel(app.SuperOptionPanel);
            app.TemperatureadvantageCEditFieldLabel.Text = 'Temperature advantage, °C';
            app.TemperatureadvantageCEditField = uieditfield(app.SuperOptionPanel, 'numeric');
            app.TemperatureadvantageCEditField.Limits = [-3 10];
            app.TemperatureadvantageCEditField.ValueDisplayFormat = '%11.2g';
            app.TemperatureadvantageCEditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureadvantageCEditFieldValueChanged, true);
            app.ProgrammaticTab = uitab(app.TabGroup);
            app.ProgrammaticTab.Title = 'Programmatic';
            app.EverynreefsEditFieldLabel = uilabel(app.ProgrammaticTab);
            app.EverynreefsEditFieldLabel.Text = 'Every n reefs';
            app.EverynreefsEditField = uieditfield(app.ProgrammaticTab, 'numeric');
            app.EverynreefsEditField.Limits = [1 1925];
            app.EverynreefsEditField.RoundFractionalValues = 'on';
            app.EverynreefsEditField.ValueDisplayFormat = '%11g';
            app.EverynreefsEditField.ValueChangedFcn = createCallbackFcn(app, @EverynreefsEditFieldValueChanged, true);
            app.EverynreefsEditField.Value = 1;
            app.ReefSubsetDropDownLabel = uilabel(app.ProgrammaticTab);
            app.ReefSubsetDropDownLabel.Text = 'Reef Subset';
            app.ReefSubsetDropDown = uidropdown(app.ProgrammaticTab);
            app.ReefSubsetDropDown.Items = {'All Reefs', 'Only Key Reefs', 'Equatorial', 'Mid Latitude', 'High Latitude', 'Skip (below)'};
            app.ReefSubsetDropDown.ValueChangedFcn = createCallbackFcn(app, @ReefSubsetDropDownValueChanged, true);
            app.ReefSubsetDropDown.Value = 'All Reefs';
            app.KeyReefsTextAreaLabel = uilabel(app.ProgrammaticTab);
            app.KeyReefsTextAreaLabel.Text = 'Key Reefs';
            app.KeyReefsTextArea = uitextarea(app.ProgrammaticTab);
            app.KeyReefsTextArea.Value = {'144 402 420 793 1541'};
            app.RunevenifnotspecifiedaboveLabel = uilabel(app.ProgrammaticTab);
            app.RunevenifnotspecifiedaboveLabel.Text = 'Run even if not specified above';
            app.WorkerthreadsSliderLabel = uilabel(app.ProgrammaticTab);
            app.WorkerthreadsSliderLabel.Text = 'Worker threads';
            app.WorkerthreadsSlider = uislider(app.ProgrammaticTab);
            app.WorkerthreadsSlider.Limits = [1 15];
            app.WorkerthreadsSlider.ValueChangedFcn = createCallbackFcn(app, @WorkerthreadsSliderValueChanged, true);
            app.WorkerthreadsSlider.Value = 3;
            app.OutputsTab = uitab(app.TabGroup);
            app.OutputsTab.Title = 'Outputs';
            app.GlobalMapsCheckBox = uicheckbox(app.OutputsTab);
            app.GlobalMapsCheckBox.ValueChangedFcn = createCallbackFcn(app, @GlobalMapsCheckBoxValueChanged, true);
            app.GlobalMapsCheckBox.Text = 'Global Maps';
            app.GlobalCoralCoverCheckBox = uicheckbox(app.OutputsTab);
            app.GlobalCoralCoverCheckBox.ValueChangedFcn = createCallbackFcn(app, @GlobalCoralCoverCheckBoxValueChanged, true);
            app.GlobalCoralCoverCheckBox.Text = 'Global Coral Cover';
            app.PanelPopulationPlotsButtonGroup = uibuttongroup(app.OutputsTab);
            app.PanelPopulationPlotsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotForKeyButtonGroupSelectionChanged, true);
            app.PanelPopulationPlotsButtonGroup.Title = '4-Panel Population Plots';
            app.AllreefsButton = uiradiobutton(app.PanelPopulationPlotsButtonGroup);
            app.AllreefsButton.Text = 'All reefs';
            app.KeyreefsButton = uiradiobutton(app.PanelPopulationPlotsButtonGroup);
            app.KeyreefsButton.Text = 'Key reefs';
            app.KeyreefsButton.Value = true;
            app.SavedataforcrossrunstressedreefplotsCheckBox = uicheckbox(app.OutputsTab);
            app.SavedataforcrossrunstressedreefplotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @CrossRunStressDataCheckBoxValueChanged, true);
            app.SavedataforcrossrunstressedreefplotsCheckBox.Text = 'Save data for cross-run stressed-reef plots';
            app.GenotypefigureCheckBox = uicheckbox(app.OutputsTab);
            app.GenotypefigureCheckBox.ValueChangedFcn = createCallbackFcn(app, @GenotypefigureCheckBoxValueChanged, true);
            app.GenotypefigureCheckBox.Text = 'Genotype figure';
            app.GrowthratefigureCheckBox = uicheckbox(app.OutputsTab);
            app.GrowthratefigureCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrowthratefigureCheckBoxValueChanged, true);
            app.GrowthratefigureCheckBox.Text = 'Growth rate figure';
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.GoButton_2 = uibutton(app.RunTab, 'push');
            app.GoButton_2.ButtonPushedFcn = createCallbackFcn(app, @GoButton_2Pushed, true);
            app.GoButton_2.Text = 'Go';
            app.CheckSelectionsTextAreaLabel = uilabel(app.RunTab);
            app.CheckSelectionsTextAreaLabel.Text = 'Check Selections';
            app.CheckSelectionsTextArea = uitextarea(app.RunTab);
            app.JSONformatLabel = uilabel(app.RunTab);
            app.JSONformatLabel.Text = '(JSON format)';
            app.SaveStateButton = uibutton(app.RunTab, 'push');
            app.SaveStateButton.ButtonPushedFcn = createCallbackFcn(app, @SaveStateButtonPushed, true);
            app.SaveStateButton.Text = 'Save State';
            app.UIAxes = uiaxes(app.RunTab);
            title(app.UIAxes, 'Progress')
            xlabel(app.UIAxes, 'Worker')
            ylabel(app.UIAxes, 'Percent Complete')
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.CoralModelEditFieldLabel = uilabel(app.SetupTab);
            app.CoralModelEditFieldLabel.Text = 'Coral Model';
            app.CoralModelEditField = uieditfield(app.SetupTab, 'text');
            app.CoralModelEditField.Value = 'e.g. D:\Github\Coral-Model-V12';
            app.SelectDirectoryButton_code = uibutton(app.SetupTab, 'push');
            app.SelectDirectoryButton_code.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButton_codePushed, true);
            app.SelectDirectoryButton_code.Text = 'Select Directory';
            app.PathsLabel = uilabel(app.SetupTab);
            app.PathsLabel.Text = 'Paths';
            app.SelectDirectoryButton_output = uibutton(app.SetupTab, 'push');
            app.SelectDirectoryButton_output.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButton_outputPushed, true);
            app.SelectDirectoryButton_output.Text = 'Select Directory';
            app.OutputBaseEditFieldLabel = uilabel(app.SetupTab);
            app.OutputBaseEditFieldLabel.Text = 'Output Base';
            app.OutputBaseEditField = uieditfield(app.SetupTab, 'text');
            app.OutputBaseEditField.Value = 'e.g. D:\CoralTest';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ModelGUI_2017b

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CoralModelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CoralModelUIFigure)
        end
    end
end